classdef FunctionBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        MorePanel          matlab.ui.container.Panel
        TangentCheckBox    matlab.ui.control.CheckBox
        NormalCheckBox     matlab.ui.control.CheckBox
        BinormalCheckBox   matlab.ui.control.CheckBox
        DotparameterLabel  matlab.ui.control.Label
        tLabel             matlab.ui.control.Label
        tEditField         matlab.ui.control.NumericEditField
        BuildButton        matlab.ui.control.Button
        t0Label            matlab.ui.control.Label
        XtEditFieldLabel   matlab.ui.control.Label
        XtEditField        matlab.ui.control.EditField
        YtEditFieldLabel   matlab.ui.control.Label
        YtEditField        matlab.ui.control.EditField
        ZtEditFieldLabel   matlab.ui.control.Label
        ZtEditField        matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function [T, N, B] = func(app, x, y, z)
        
            % CONVERT TO COLUMN VECTOR
            x = x(:);
            y = y(:);
            z = z(:);
            % DERIVATIVE OF CURVE
            dx = gradient(x);
            dy = gradient(y);
            dz = gradient(z);
            dr = [dx dy dz];
            
            % TANGENT
            T = dr./app.mag(dr,3);
            
            % DERIVIATIVE OF TANGENT
            dTx = gradient(T(:,1));
            dTy = gradient(T(:,2));
            dTz = gradient(T(:,3));
            dT = [dTx dTy dTz];
            
            % NORMAL
            N = dT./app.mag(dT,3);
            % BINORMAL
            B = cross(T,N);
        end
        
        function N = mag(~, T,n)
            %MAGNITUDE
            N = sum(abs(T).^2,2).^(1/2);
            d = find(N==0);
            N(d) = eps*ones(size(d));
            N = N(:,ones(n,1));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuildButton
        function BuildButtonPushed(app, event)
            t = 0:0.01:10*pi;
            x = str2func(strcat('@(t)',app.XtEditField.Value));
            y = str2func(strcat('@(t)',app.YtEditField.Value));
            z = str2func(strcat('@(t)',app.ZtEditField.Value));
            
%             x = sin(t);
%             y = cos(t);
%             z = t;
            x = x(t);
            y = y(t);
            z = z(t);
            
            plot3(app.UIAxes, x,y,z);
            hold(app.UIAxes, "on");
            
            [T, N, B]=app.func(x, y, z);
            
            t_input = app.tEditField.Value;
            n = find(t == t_input);
            
            if app.TangentCheckBox.Value == 1
                quiver3(app.UIAxes,x(1,n), y(1,n), z(1,n), T(n,1)',T(n,2)',T(n,3)','color','r');
            end
            if app.NormalCheckBox.Value == 1
                quiver3(app.UIAxes,x(1,n), y(1,n), z(1,n), N(n,1)',N(n,2)',N(n,3)','color','g');
            end
            if app.BinormalCheckBox.Value == 1
                quiver3(app.UIAxes,x(1,n), y(1,n), z(1,n), B(n,1)',B(n,2)',B(n,3)','color','b');
            end
            hold (app.UIAxes,'off');
        end

        % Value changed function: TangentCheckBox
        function TangentCheckBoxValueChanged(app, event)
            value = app.TangentCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MorePanel = uipanel(app.UIFigure);
            app.MorePanel.Title = 'More';
            app.TangentCheckBox = uicheckbox(app.MorePanel);
            app.TangentCheckBox.ValueChangedFcn = createCallbackFcn(app, @TangentCheckBoxValueChanged, true);
            app.TangentCheckBox.Text = 'Tangent';
            app.NormalCheckBox = uicheckbox(app.MorePanel);
            app.NormalCheckBox.Text = 'Normal';
            app.BinormalCheckBox = uicheckbox(app.MorePanel);
            app.BinormalCheckBox.Text = 'Binormal';
            app.DotparameterLabel = uilabel(app.MorePanel);
            app.DotparameterLabel.Text = 'Dot parameter:';
            app.tLabel = uilabel(app.MorePanel);
            app.tLabel.Text = 't   =  ';
            app.tEditField = uieditfield(app.MorePanel, 'numeric');
            app.BuildButton = uibutton(app.MorePanel, 'push');
            app.BuildButton.ButtonPushedFcn = createCallbackFcn(app, @BuildButtonPushed, true);
            app.BuildButton.Text = 'Build';
            app.t0Label = uilabel(app.MorePanel);
            app.t0Label.Text = '(t > 0)';
            app.XtEditFieldLabel = uilabel(app.UIFigure);
            app.XtEditFieldLabel.Text = 'X(t)';
            app.XtEditField = uieditfield(app.UIFigure, 'text');
            app.XtEditField.Tooltip = {''};
            app.YtEditFieldLabel = uilabel(app.UIFigure);
            app.YtEditFieldLabel.Text = 'Y(t)';
            app.YtEditField = uieditfield(app.UIFigure, 'text');
            app.YtEditField.Tooltip = {''};
            app.ZtEditFieldLabel = uilabel(app.UIFigure);
            app.ZtEditFieldLabel.Text = 'Z(t)';
            app.ZtEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FunctionBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end