classdef LineBuilder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        Button         matlab.ui.control.Button
        EditField      matlab.ui.control.EditField
        Label          matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label_3        matlab.ui.control.Label
        Label_4        matlab.ui.control.Label
        EditField_2    matlab.ui.control.EditField
        EditField_3    matlab.ui.control.EditField
        xtLabel        matlab.ui.control.Label
        ytLabel        matlab.ui.control.Label
        ztLabel        matlab.ui.control.Label
        EditField_4    matlab.ui.control.EditField
        tLabel         matlab.ui.control.Label
        ButtonGroup    matlab.ui.container.ButtonGroup
        CheckBox       matlab.ui.control.CheckBox
        CheckBox_2     matlab.ui.control.CheckBox
        CheckBox_3     matlab.ui.control.CheckBox
        CheckBox_7     matlab.ui.control.CheckBox
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        CheckBox_4     matlab.ui.control.CheckBox
        CheckBox_5     matlab.ui.control.CheckBox
        CheckBox_6     matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.cla;
            daspect(app.UIAxes, [1 1 1]);
            syms t;
            try
            
            %Входные данные
            X_input = str2sym(app.EditField.Value);
            Y_input = str2sym(app.EditField_2.Value);
            Z_input = str2sym(app.EditField_3.Value);
            t_Param = str2double(app.EditField_4.Value);
            
            %Координаты точки
            X_dot = double(subs(X_input,t,t_Param));
            Y_dot = double(subs(Y_input,t,t_Param));
            Z_dot = double(subs(Z_input,t,t_Param));
            
            %Построение кривой и указание точки
            fplot3(app.UIAxes,X_input,Y_input,Z_input, [-3 3]);
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            plot3(app.UIAxes,X_dot,Y_dot,Z_dot,'*');
            
            %Вычисление 1, 2 и 3 производных
            dX = subs(diff(X_input,1,t),t,t_Param);
            dY = subs(diff(Y_input,1,t),t,t_Param);
            dZ = subs(diff(Z_input,1,t),t,t_Param);
            
            ddX = subs(diff(X_input,2,t),t,t_Param);
            ddY = subs(diff(Y_input,2,t),t,t_Param);
            ddZ = subs(diff(Z_input,2,t),t,t_Param);
            
            dddX = subs(diff(X_input,3,t),t,t_Param);
            dddY = subs(diff(Y_input,3,t),t,t_Param);
            dddZ = subs(diff(Z_input,3,t),t,t_Param);
            
            %%Построение ребер репера
            
            %r - направляющий вектор в точке, dr,ddr - 1 и 2 производные
            r = [X_dot Y_dot Z_dot];
            dr = [dX dY dZ];
            ddr = [ddX ddY ddZ];
            
            %Касательная
            T = simplify(dr./norm(dr));
            if app.CheckBox.Value == 1
                if app.CheckBox_7.Value == 1
                    plot3(app.UIAxes, [r(1)-T(1) r(1)+T(1)], [r(2)-T(2) r(2)+T(2)], [r(3)-T(3) r(3)+T(3)]);
                else
                    quiver3(app.UIAxes, r(1), r(2), r(3), T(1), T(2), T(3));
                end
            end
            
            %Нормаль
            N = simplify((cross(cross(dr,ddr),dr))./(norm(cross(dr,ddr).*norm(dr))));
            if app.CheckBox_2.Value == 1
                if app.CheckBox_7.Value == 1
                    plot3(app.UIAxes, [r(1)-N(1) r(1)+N(1)], [r(2)-N(2) r(2)+N(2)], [r(3)-N(3) r(3)+N(3)]);
                else
                    quiver3(app.UIAxes, r(1), r(2), r(3), N(1), N(2), N(3));
                end
            end
            
            %Бинормаль
            B = simplify(cross(dr, ddr)./norm(cross(dr, ddr)));
            if app.CheckBox_3.Value == 1
                if app.CheckBox_7.Value == 1
                    plot3(app.UIAxes, [r(1)-B(1) r(1)+B(1)], [r(2)-B(2) r(2)+B(2)], [r(3)-B(3) r(3)+B(3)]);
                else
                    quiver3(app.UIAxes, r(1), r(2), r(3), B(1), B(2), B(3));
                end
            end
            %%
            
            %%Построение граней репера
            syms x y z;
            %Соприкасающаяся плоскость
            ContactMatrix = [x-X_dot y-Y_dot z-Z_dot;dX dY dZ;ddX ddY ddZ];
            ContactPlane = simplify(det(ContactMatrix));
            
            %Нормальная плоскость
            NormalPlane = simplify((x-X_dot).*dX+(y-Y_dot).*dY+(z-Z_dot).*dZ);
            
            %Спрямляющая плоскость
            RectifyingMatrix = [x-X_dot y-Y_dot z-Z_dot;dX dY dZ;det([dY dZ; ddY ddZ]) det([dZ dX;ddZ ddX]) det([dX dY;ddX ddY])];
            RectifyingPlane = simplify(det(RectifyingMatrix));
            
            %Построение граней на графике
            interval = [double(X_dot-1) double(X_dot+1) double(Y_dot-1) double(Y_dot+1) double(Z_dot-1) double(Z_dot+1)];
            if app.CheckBox_4.Value == 1
                fimplicit3(app.UIAxes,ContactPlane,interval,'EdgeColor','none','FaceAlpha',0.7);
            end
            if app.CheckBox_5.Value == 1
                fimplicit3(app.UIAxes,NormalPlane,interval,'EdgeColor','none','FaceAlpha',0.7);
            end
            if app.CheckBox_6.Value == 1
                fimplicit3(app.UIAxes,RectifyingPlane,interval,'EdgeColor','none','FaceAlpha',0.7);
            end
            %%
            
            %%Вычисление кривизны и кручения
            
            %Кривизна
            Curvature = double(simplify((((ddZ*dY-ddY*dZ)^2+(ddX*dZ-ddZ*dX)^2+(ddY*dX-ddX*dY)^2)^0.5)/((dX^2+dY^2+dZ^2)^(3/2))));
            app.Label_2.Text = num2str(Curvature);
            
            %Кручение
            Torsion = double(simplify((dddX*(dY*ddZ-ddY*dZ)+dddY*(ddX*dZ-dX*ddZ)+dddZ*(dX*ddY-ddX*dY))/((dY*ddZ-ddY*dZ)^2+(ddX*dZ-dX*ddZ)^2+(dX*ddY-ddX*dY)^2)));
            app.Label_4.Text = num2str(Torsion);
            %%
            
            catch
                %%Сообщение об ошибке
                errordlg('Произошла непредвиденная ошибка, проверьте введенные данные', 'Error message');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Кривизна:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Кручение:';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.xtLabel = uilabel(app.UIFigure);
            app.xtLabel.Text = 'x(t)';
            app.ytLabel = uilabel(app.UIFigure);
            app.ytLabel.Text = 'y(t)';
            app.ztLabel = uilabel(app.UIFigure);
            app.ztLabel.Text = 'z(t)';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = 'Параметр точки(t)';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Ребра репера';
            app.CheckBox = uicheckbox(app.ButtonGroup);
            app.CheckBox.Text = 'Касательная';
            app.CheckBox_2 = uicheckbox(app.ButtonGroup);
            app.CheckBox_2.Text = 'Главная нормаль';
            app.CheckBox_3 = uicheckbox(app.ButtonGroup);
            app.CheckBox_3.Text = 'Бинормаль';
            app.CheckBox_7 = uicheckbox(app.ButtonGroup);
            app.CheckBox_7.Text = 'Прямые';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.Title = 'Грани репера';
            app.CheckBox_4 = uicheckbox(app.ButtonGroup_2);
            app.CheckBox_4.Text = 'Соприкасающая';
            app.CheckBox_5 = uicheckbox(app.ButtonGroup_2);
            app.CheckBox_5.Text = 'Нормальная';
            app.CheckBox_6 = uicheckbox(app.ButtonGroup_2);
            app.CheckBox_6.Text = 'Спрямляющая';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LineBuilder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end